classdef Potts360 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Potts360UIFigure                matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        RandomizeButton                 matlab.ui.control.Button
        StartStopButton                 matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        ExternamalFieldMagnitudeSliderLabel  matlab.ui.control.Label
        ExternamalFieldMagnitudeSlider  matlab.ui.control.Slider
        ofVariantsSpinnerLabel          matlab.ui.control.Label
        ofVariantsSpinner               matlab.ui.control.Spinner
        TemperatureSlider               matlab.ui.control.Slider
        NeighborCouplingLabel           matlab.ui.control.Label
        NeighborCouplingSlider          matlab.ui.control.Slider
        StepsFrameSpinnerLabel          matlab.ui.control.Label
        StepsFrameSpinner               matlab.ui.control.Spinner
        Knob                            matlab.ui.control.Knob
        Temperature10Label              matlab.ui.control.Label
        J_magLabel                      matlab.ui.control.Label
        h_thetaLabel                    matlab.ui.control.Label
        h_magLabel                      matlab.ui.control.Label
        FieldDirectionLabel             matlab.ui.control.Label
        ExternalLabel                   matlab.ui.control.Label
        InternalFieldDirectionGaugeLabel  matlab.ui.control.Label
        InternalFieldDirectionGauge     matlab.ui.control.Gauge
        InternalFieldStrengthGaugeLabel  matlab.ui.control.Label
        InternalFieldStrengthGauge      matlab.ui.control.LinearGauge
        RowsEditFieldLabel              matlab.ui.control.Label
        RowsEditField                   matlab.ui.control.NumericEditField
        ColumnsEditFieldLabel           matlab.ui.control.Label
        ColumnsEditField                matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        Model   PottsModel
        Running logical
        J       double
        h       double
        h_theta double
        N       double
        alpha   double
        P_steps double
    end

    methods (Access = private)
    
        function Start(app)
            app.Running = true;
            app.Lamp.Color = 'green';
            count = 0;
            while isvalid(app) && app.Running
                app.Model.MC_step(app.N,app.J,app.h,app.h_theta,app.alpha);
                count = count + 1;
                pause(0.000001);
                if ~isvalid(app)
                    return;
                end
                if isvalid(app) && count > app.P_steps
                    app.Model.plot();
                    drawnow();
                    app.Report();
                    count = 0;
                end
                
            end
        end
        
        function Stop(app)
            app.Running = false;
            app.Lamp.Color = 'red';
            drawnow();
        end
        
        function Report(app)
            if isvalid(app)
                X = app.Model.state;
                Z = 1.0*exp(1i*X);
                Zsum = sum(sum(Z));
                app.InternalFieldDirectionGauge.Value = 180*angle(Zsum)/pi;
                mn = app.Model.m*app.Model.n;
                app.InternalFieldStrengthGauge.Value = 100*abs(Zsum)/mn;
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function Begin(app)
            app.N = app.ofVariantsSpinner.Value;
            app.Model = PottsModel(app.UIAxes,100,100,app.N);
            app.Model.plot();
            app.J = app.NeighborCouplingSlider.Value;
            app.J_magLabel.Text = sprintf('%f',app.NeighborCouplingSlider.Value);
            app.h = app.ExternamalFieldMagnitudeSlider.Value;
            app.h_theta = app.Knob.Value;
            app.alpha = 10^(-app.TemperatureSlider.Value);
            app.P_steps = app.StepsFrameSpinner.Value;
            app.h_thetaLabel.Text = sprintf('%0.1f [deg]',app.Knob.Value);
            app.h_magLabel.Text = sprintf('%f',app.ExternamalFieldMagnitudeSlider);
            app.Temperature10Label.Text = sprintf('Temperature: %0.3e',10^(app.TemperatureSlider.Value));
            app.RowsEditField.Value = app.Model.m;
            app.ColumnsEditField.Value = app.Model.n;
            app.Report();
        end

        % Button pushed function: RandomizeButton
        function Randomize(app, event)
            app.Model.randomize(app.N);
            app.Model.plot();
            app.Report();
        end

        % Callback function
        function Update(app, event)
            app.Model.plot();
        end

        % Button pushed function: StartStopButton
        function StartStop(app, event)
            if app.Running
                app.Stop();
            else
                app.Start();
            end
        end

        % Callback function: Knob
        function set_theta_h(app, event)
           value = app.Knob.Value;
            app.h_theta = value*pi/180;
            app.h_thetaLabel.Text = sprintf('%0.1f [deg]',value);
        end

        % Callback function: ExternamalFieldMagnitudeSlider
        function set_h(app, event)
            value = app.ExternamalFieldMagnitudeSlider.Value;
            app.h = value;
            app.h_magLabel.Text = sprintf('%f',value);
        end

        % Callback function: TemperatureSlider
        function set_Temp(app, event)
            logTemp = app.TemperatureSlider.Value;
            app.alpha = 10^(-logTemp);
            app.Temperature10Label.Text = sprintf('Temperature: %0.3e',10^(logTemp));
        end

        % Callback function: NeighborCouplingSlider
        function set_J(app, event)
            value = app.NeighborCouplingSlider.Value;
            app.J = value;
            app.J_magLabel.Text = sprintf('%f',value);
        end

        % Value changed function: ofVariantsSpinner
        function set_N(app, event)
            value = app.ofVariantsSpinner.Value;
            app.N = value;
        end

        % Value changed function: StepsFrameSpinner
        function set_plot_steps(app, event)
            value = app.StepsFrameSpinner.Value;
            app.P_steps = value;
        end

        % Callback function
        function set_th(app, event)

        end

        % Close request function: Potts360UIFigure
        function Exit(app, event)
            delete(app.UIAxes)
            delete(app)
            return;
        end

        % Value changed function: ColumnsEditField, RowsEditField
        function Resize(app, event)
            m = app.ColumnsEditField.Value;
            n = app.RowsEditField.Value;
            app.Model = PottsModel(app.UIAxes,m,n,app.N);
            
        end

        % Callback function
        function Potts360UIFigureSizeChanged(app, event)
%             p = app.Potts360UIFigure.Position;
            app.UIAxes.Position(3) = app.Potts360UIFigure.Position(3) - 286;
            app.UIAxes.Position(4) = app.Potts360UIFigure.Position(4);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Potts360UIFigure = uifigure;
            app.Potts360UIFigure.Name = 'Potts 360';
            app.Potts360UIFigure.CloseRequestFcn = createCallbackFcn(app, @Exit, true);
            app.UIAxes = uiaxes(app.Potts360UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = {'[ ]'};
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = {'[ ]'};
            app.RandomizeButton = uibutton(app.Potts360UIFigure, 'push');
            app.RandomizeButton.ButtonPushedFcn = createCallbackFcn(app, @Randomize, true);
            app.RandomizeButton.Text = 'Randomize';
            app.StartStopButton = uibutton(app.Potts360UIFigure, 'push');
            app.StartStopButton.ButtonPushedFcn = createCallbackFcn(app, @StartStop, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.Lamp = uilamp(app.Potts360UIFigure);
            app.ExternamalFieldMagnitudeSliderLabel = uilabel(app.Potts360UIFigure);
            app.ExternamalFieldMagnitudeSliderLabel.Text = 'Externamal Field Magnitude:';
            app.ExternamalFieldMagnitudeSlider = uislider(app.Potts360UIFigure);
            app.ExternamalFieldMagnitudeSlider.Limits = [0 25];
            app.ExternamalFieldMagnitudeSlider.ValueChangedFcn = createCallbackFcn(app, @set_h, true);
            app.ExternamalFieldMagnitudeSlider.ValueChangingFcn = createCallbackFcn(app, @set_h, true);
            app.ofVariantsSpinnerLabel = uilabel(app.Potts360UIFigure);
            app.ofVariantsSpinnerLabel.Text = '# of Variants';
            app.ofVariantsSpinner = uispinner(app.Potts360UIFigure);
            app.ofVariantsSpinner.Limits = [1 32];
            app.ofVariantsSpinner.ValueChangedFcn = createCallbackFcn(app, @set_N, true);
            app.ofVariantsSpinner.Value = 8;
            app.TemperatureSlider = uislider(app.Potts360UIFigure);
            app.TemperatureSlider.Limits = [-5 5];
            app.TemperatureSlider.ValueChangedFcn = createCallbackFcn(app, @set_Temp, true);
            app.TemperatureSlider.ValueChangingFcn = createCallbackFcn(app, @set_Temp, true);
            app.NeighborCouplingLabel = uilabel(app.Potts360UIFigure);
            app.NeighborCouplingLabel.Text = 'Neighbor Coupling:';
            app.NeighborCouplingSlider = uislider(app.Potts360UIFigure);
            app.NeighborCouplingSlider.Limits = [0 25];
            app.NeighborCouplingSlider.ValueChangedFcn = createCallbackFcn(app, @set_J, true);
            app.NeighborCouplingSlider.ValueChangingFcn = createCallbackFcn(app, @set_J, true);
            app.NeighborCouplingSlider.Value = 1;
            app.StepsFrameSpinnerLabel = uilabel(app.Potts360UIFigure);
            app.StepsFrameSpinnerLabel.Text = 'Steps/Frame';
            app.StepsFrameSpinner = uispinner(app.Potts360UIFigure);
            app.StepsFrameSpinner.Limits = [1 Inf];
            app.StepsFrameSpinner.ValueChangedFcn = createCallbackFcn(app, @set_plot_steps, true);
            app.StepsFrameSpinner.Value = 1;
            app.Knob = uiknob(app.Potts360UIFigure, 'continuous');
            app.Knob.Limits = [-180 180];
            app.Knob.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @set_theta_h, true);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @set_theta_h, true);
            app.Temperature10Label = uilabel(app.Potts360UIFigure);
            app.Temperature10Label.Text = 'Temperature: 1.0';
            app.J_magLabel = uilabel(app.Potts360UIFigure);
            app.J_magLabel.Text = 'J_mag';
            app.h_thetaLabel = uilabel(app.Potts360UIFigure);
            app.h_thetaLabel.Text = 'h_theta';
            app.h_magLabel = uilabel(app.Potts360UIFigure);
            app.h_magLabel.Text = 'h_mag';
            app.FieldDirectionLabel = uilabel(app.Potts360UIFigure);
            app.FieldDirectionLabel.Text = 'Field Direction:';
            app.ExternalLabel = uilabel(app.Potts360UIFigure);
            app.ExternalLabel.Text = 'External';
            app.InternalFieldDirectionGaugeLabel = uilabel(app.Potts360UIFigure);
            app.InternalFieldDirectionGaugeLabel.Text = 'Internal Field Direction';
            app.InternalFieldDirectionGauge = uigauge(app.Potts360UIFigure, 'circular');
            app.InternalFieldDirectionGauge.Limits = [-180 180];
            app.InternalFieldDirectionGauge.MajorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.InternalFieldStrengthGaugeLabel = uilabel(app.Potts360UIFigure);
            app.InternalFieldStrengthGaugeLabel.Text = 'Internal Field Strength';
            app.InternalFieldStrengthGauge = uigauge(app.Potts360UIFigure, 'linear');
            app.RowsEditFieldLabel = uilabel(app.Potts360UIFigure);
            app.RowsEditFieldLabel.Text = 'Rows';
            app.RowsEditField = uieditfield(app.Potts360UIFigure, 'numeric');
            app.RowsEditField.Limits = [1 999];
            app.RowsEditField.ValueDisplayFormat = '%.0f';
            app.RowsEditField.ValueChangedFcn = createCallbackFcn(app, @Resize, true);
            app.RowsEditField.Value = 100;
            app.ColumnsEditFieldLabel = uilabel(app.Potts360UIFigure);
            app.ColumnsEditFieldLabel.Text = 'Columns';
            app.ColumnsEditField = uieditfield(app.Potts360UIFigure, 'numeric');
            app.ColumnsEditField.Limits = [1 999];
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @Resize, true);
            app.ColumnsEditField.Value = 100;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Potts360

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Potts360UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Begin)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Potts360UIFigure)
        end
    end
end